classdef AttendanceSystem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        AttendanceSystemWithFaceRecognitionLabel  matlab.ui.control.Label
        TrainSystemTab                  matlab.ui.container.Tab
        UploadTrainImage                matlab.ui.control.Button
        UploadthefiletotrainfacesLabel  matlab.ui.control.Label
        TrainFaceRecognizerLabel        matlab.ui.control.Label
        DisplayTrainImage               matlab.ui.control.UIAxes
        TrainSystemButton               matlab.ui.control.Button
        FaceCountLabel                  matlab.ui.control.Label
        fCount                          matlab.ui.control.Label
        EnterthenamesofpeopleLabel      matlab.ui.control.Label
        Person1EditFieldLabel           matlab.ui.control.Label
        name1                           matlab.ui.control.EditField
        Person2EditFieldLabel           matlab.ui.control.Label
        name2                           matlab.ui.control.EditField
        Person3EditFieldLabel           matlab.ui.control.Label
        name3                           matlab.ui.control.EditField
        Person4EditFieldLabel           matlab.ui.control.Label
        name4                           matlab.ui.control.EditField
        RecognizeFacesFromImageLabel    matlab.ui.control.Label
        UploadimageforrecognitionLabel  matlab.ui.control.Label
        uploadTest                      matlab.ui.control.Button
        displayTestImg                  matlab.ui.control.UIAxes
        RecognitionSystemTab            matlab.ui.container.Tab
    end

    
    properties (Access = private)
        TrainedImage = 0;% Description
        testImage
        faces % simple faces
        normFaces % 
        mu % Description
        eigVec % eigen vectors
        eigFaces % eigen faces
        omega % omega value
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadTrainImage
        function UploadTrainImageButtonPushed(app, event)
        filter = {'*.*'};
        [fileName,filePath] = uigetfile(filter);
        app.TrainedImage = imread(fullfile(filePath,fileName));
        I = imshow(app.TrainedImage, 'Parent', app.DisplayTrainImage);
        app.DisplayTrainImage.XLim = [0 I.XData(2)]; app.DisplayTrainImage.YLim = [0 I.YData(2)];
            
        end

        % Button pushed function: TrainSystemButton
        function TrainSystemButtonPushed(app, event)
            if app.TrainedImage == 0
                f = uifigure;
                uialert(f,"File not uploaded.","Invalid File");
                return;
            end
                I = app.TrainedImage;
                app.faces = extractFaces(I,50);
                [app.normFaces,app.mu] = featureNormalize(app.faces);
                [app.eigVec, eigVal] = pca(app.normFaces);
                [app.eigFaces,app.omega] = generateEiganFaces(app.normFaces,app.eigVec);
                app.fCount.Text = int2str(size(app.faces,2));
        end

        % Button pushed function: uploadTest
        function uploadTestButtonPushed(app, event)
            filter = {'*.*'};
            [fileName,filePath] = uigetfile(filter);
            app.testImage =imread(fullfile(filePath,fileName));
            I = imshow(app.testImage, 'Parent', app.displayTestImg);
            app.displayTestImg.XLim = [0 I.XData(2)]; app.displayTestImg.YLim = [0 I.YData(2)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.AttendanceSystemWithFaceRecognitionLabel = uilabel(app.HomeTab);
            app.AttendanceSystemWithFaceRecognitionLabel.Text = 'Attendance System With Face Recognition';
            app.TrainSystemTab = uitab(app.TabGroup);
            app.TrainSystemTab.Title = 'Train System';
            app.UploadTrainImage = uibutton(app.TrainSystemTab, 'push');
            app.UploadTrainImage.ButtonPushedFcn = createCallbackFcn(app, @UploadTrainImageButtonPushed, true);
            app.UploadTrainImage.Text = 'Upload';
            app.UploadthefiletotrainfacesLabel = uilabel(app.TrainSystemTab);
            app.UploadthefiletotrainfacesLabel.Text = 'Upload the file to train faces';
            app.TrainFaceRecognizerLabel = uilabel(app.TrainSystemTab);
            app.TrainFaceRecognizerLabel.Text = 'Train Face Recognizer';
            app.DisplayTrainImage = uiaxes(app.TrainSystemTab);
            title(app.DisplayTrainImage, '')
            xlabel(app.DisplayTrainImage, '')
            ylabel(app.DisplayTrainImage, '')
            app.DisplayTrainImage.XTick = [];
            app.DisplayTrainImage.YTick = [];
            app.TrainSystemButton = uibutton(app.TrainSystemTab, 'push');
            app.TrainSystemButton.ButtonPushedFcn = createCallbackFcn(app, @TrainSystemButtonPushed, true);
            app.TrainSystemButton.Text = 'Train System';
            app.FaceCountLabel = uilabel(app.TrainSystemTab);
            app.FaceCountLabel.Text = 'Face Count:';
            app.fCount = uilabel(app.TrainSystemTab);
            app.fCount.Text = '';
            app.EnterthenamesofpeopleLabel = uilabel(app.TrainSystemTab);
            app.EnterthenamesofpeopleLabel.Text = 'Enter the names of people';
            app.Person1EditFieldLabel = uilabel(app.TrainSystemTab);
            app.Person1EditFieldLabel.Text = 'Person #1';
            app.name1 = uieditfield(app.TrainSystemTab, 'text');
            app.Person2EditFieldLabel = uilabel(app.TrainSystemTab);
            app.Person2EditFieldLabel.Text = 'Person #2';
            app.name2 = uieditfield(app.TrainSystemTab, 'text');
            app.Person3EditFieldLabel = uilabel(app.TrainSystemTab);
            app.Person3EditFieldLabel.Text = 'Person #3';
            app.name3 = uieditfield(app.TrainSystemTab, 'text');
            app.Person4EditFieldLabel = uilabel(app.TrainSystemTab);
            app.Person4EditFieldLabel.Text = 'Person #4';
            app.name4 = uieditfield(app.TrainSystemTab, 'text');
            app.RecognitionSystemTab = uitab(app.TabGroup);
            app.RecognitionSystemTab.Title = 'Recognition System';
            app.RecognizeFacesFromImageLabel = uilabel(app.RecognitionSystemTab);
            app.RecognizeFacesFromImageLabel.Text = 'Recognize Faces From Image';
            app.UploadimageforrecognitionLabel = uilabel(app.RecognitionSystemTab);
            app.UploadimageforrecognitionLabel.Text = 'Upload image for recognition';
            app.uploadTest = uibutton(app.RecognitionSystemTab, 'push');
            app.uploadTest.ButtonPushedFcn = createCallbackFcn(app, @uploadTestButtonPushed, true);
            app.uploadTest.Text = 'upload';
            app.displayTestImg = uiaxes(app.RecognitionSystemTab);
            title(app.displayTestImg, '')
            xlabel(app.displayTestImg, '')
            ylabel(app.displayTestImg, '')
            app.displayTestImg.XTick = [];
            app.displayTestImg.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AttendanceSystem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end